classdef matcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label                          matlab.ui.control.Label
        TransmissionLinePropertiesPanel_2  matlab.ui.container.Panel
        tlR0                           matlab.ui.control.NumericEditField
        CharachteristicImpedanceLabel_2  matlab.ui.control.Label
        StubPropertiesButtonGroup      matlab.ui.container.ButtonGroup
        sI                             matlab.ui.control.NumericEditField
        ImpedanceImaginaryPartEditFieldLabel_2  matlab.ui.control.Label
        sR                             matlab.ui.control.NumericEditField
        ImpedanceRealPartEditFieldLabel_2  matlab.ui.control.Label
        slabel                         matlab.ui.control.Label
        GeneralImpedanceButton         matlab.ui.control.RadioButton
        ShortCircuitTerminationButton  matlab.ui.control.RadioButton
        OpenCircuitTerminationButton   matlab.ui.control.RadioButton
        DesignNetworkButton            matlab.ui.control.Button
        SourcePropertiesPanel          matlab.ui.container.Panel
        wavelength                     matlab.ui.control.NumericEditField
        WavelengthmLabel               matlab.ui.control.Label
        MatchingNetworkTypeDropDownLabel  matlab.ui.control.Label
        MatchingNetworkTypeDropDown    matlab.ui.control.DropDown
        LoadPropertiesPanel            matlab.ui.container.Panel
        ZrealjImagLabel                matlab.ui.control.Label
        zlImag                         matlab.ui.control.NumericEditField
        ImpedanceImaginaryPartEditFieldLabel  matlab.ui.control.Label
        zlReal                         matlab.ui.control.NumericEditField
        ImpedanceRealPartEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function [gamma, circle] = gammaCircle(app, Z_L, Z_0)

            mag = abs(Z_0);
            Zn_0 = Z_0 / mag;
            Zn_L = Z_L / mag;
            gamma = ((Zn_L - Zn_0)/(Zn_L + Zn_0));
            gammaMag = abs(gamma);

            syms x y
            circle = x^2 + y^2 == gammaMag^2;
            %circle = (gammaMag*cos(theta_Gamma))^2 + (gammaMag*sin(theta_Gamma))^2 == gammaMag;
        end

        function [x, y] = enterChart(app, Z_L, R_0)
            [gamma, ~] = gammaCircle(app, Z_L, R_0);
            gammaMag = abs(gamma);
            x = gammaMag * cos(angle(gamma));
            y = gammaMag * sin(angle(gamma));

        end

        function lineEqn = getStraightLine(app, a, b)
            m = (a(2)-b(2)) / (a(1)-b(1));
            c = b(2) - m*b(1);
            syms x y
            lineEqn = y == m*x + c;
        end

        function [Y_lx, Y_ly] = getAdmittancePt(app, Z_lx, Z_ly)
            % Gets admittance from the load impedance
            Y_lx = -Z_lx;
            Y_ly = -Z_ly;

        end

        function [toX, toY, d] = moveToR1onChart(app, Z_L, R_0)
            % CLOCKWISE
            % Move from (fromX, fromY) to the intersection with the
            % constant r = 1 circle. Returns the distances moved from Z_L
            % to each point of intersection and returns these points
            % aswell.

            syms x y
            constRCirc = (x-0.5)^2 + y^2 == 0.5^2;
            [~, gammaCirc] = gammaCircle(app, Z_L, R_0);
            [toX, toY] = solve(constRCirc, gammaCirc);

            ResAngles = angle(toX + 1j*toY); % The gamma angle of each point of intersection
            [xz, yz] = enterChart(app, Z_L, R_0);
            zlAngle = angle(xz + 1j*yz);

            %             for i=1:2
            %                 if (zLAngle > ResAngles(i))
            %                     radialDist(i) = zLAngle - ResAngles(i);
            %                 else
            %                     radialDist(i) = (2*pi) - (zLAngle - ResAngles(i));
            %                 end
            %
            %             end

            radialDist = double(zlAngle - double(ResAngles));

            d = (0.5*rad2deg(radialDist)/360);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Update the label value to match the user input
            if app.zlImag.Value < 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "-j" + num2str(abs(app.zlImag.Value)) + " Ω";
            elseif app.zlImag.Value == 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + " Ω";

            else
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "+j" + num2str(app.zlImag.Value) + " Ω" ;
            end
            if app.zlReal.Value == 0 && app.zlImag.Value ~= 0
                if app.zlImag.Value == 0
                    app.ZrealjImagLabel.Text = "Z = 0 Ω" ;
                elseif app.zlImag.Value < 0
                    app.ZrealjImagLabel.Text = "Z = -j" + num2str(abs(app.zlImag.Value)) + " Ω" ;
                else
                    app.ZrealjImagLabel.Text = "Z = j" + num2str(app.zlImag.Value) + " Ω" ;
                end
            end

            % Update the label value to match the user input
            if app.sI.Value < 0
                app.slabel.Text = "Z = " +num2str(app.sR.Value) + "-j" + num2str(abs(app.sI.Value)) + " Ω";
            elseif app.sI.Value == 0
                app.slabel.Text = "Z = " +num2str(app.sI.Value) + " Ω";

            else
                app.slabel.Text = "Z = " +num2str(app.sR.Value) + "+j" + num2str(app.sI.Value) + " Ω" ;
            end
            if app.sR.Value == 0 && app.sI.Value ~= 0
                if app.sI.Value == 0
                    app.slabel.Text = "Z = 0 Ω" ;
                elseif app.sI.Value < 0
                    app.slabel.Text = "Z = -j" + num2str(abs(app.sI.Value)) + " Ω" ;
                else
                    app.slabel.Text = "Z = j" + num2str(app.sI.Value) + " Ω" ;
                end
            end

            app.sI.Enable = 'off';
            app.sR.Enable = 'off';
            app.sI.Value = 0;
            app.sR.Value = 0;
            app.slabel.Text = "Z = 0 Ω";

        end

        % Value changed function: zlReal
        function zlRealValueChanged(app, event)
            % Update the label value to match the user input
            if app.zlImag.Value < 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "-j" + num2str(abs(app.zlImag.Value)) + " Ω";
            elseif app.zlImag.Value == 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + " Ω";

            else
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "+j" + num2str(app.zlImag.Value) + " Ω" ;
            end
            if app.zlReal.Value == 0 && app.zlImag.Value ~= 0
                if app.zlImag.Value == 0
                    app.ZrealjImagLabel.Text = "Z = 0 Ω" ;
                elseif app.zlImag.Value < 0
                    app.ZrealjImagLabel.Text = "Z = -j" + num2str(abs(app.zlImag.Value)) + " Ω" ;
                else
                    app.ZrealjImagLabel.Text = "Z = j" + num2str(app.zlImag.Value) + " Ω" ;
                end
            end
        end

        % Value changed function: zlImag
        function zlImagValueChanged(app, event)
            % Update the label value to match the user input
            if app.zlImag.Value < 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "-j" + num2str(abs(app.zlImag.Value)) + " Ω";
            elseif app.zlImag.Value == 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + " Ω";

            else
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "+j" + num2str(app.zlImag.Value) + " Ω" ;
            end
            if app.zlReal.Value == 0 && app.zlImag.Value ~= 0
                if app.zlImag.Value == 0
                    app.ZrealjImagLabel.Text = "Z = 0 Ω" ;
                elseif app.zlImag.Value < 0
                    app.ZrealjImagLabel.Text = "Z = -j" + num2str(abs(app.zlImag.Value)) + " Ω" ;
                else
                    app.ZrealjImagLabel.Text = "Z = j" + num2str(app.zlImag.Value) + " Ω" ;
                end
            end
        end

        % Button pushed function: DesignNetworkButton
        function DesignNetworkButtonPushed(app, event)
            % Clear Plot
            cla(app.UIAxes)

            % Design the matching network and view the result in a pop up.

            Z_L = app.zlReal.Value + 1j * app.zlImag.Value; % Load Impedance
            TLR_0 = app.tlR0.Value; % Transmission Line Charachteristic Impedance
            Z_s = app.sR.Value + 1j* app.sI.Value; % Stub Termination Impedance
            lambda = app.wavelength.Value;
            userSel = app.MatchingNetworkTypeDropDown.Value;

            % We need to model the smith chart

            switch userSel
                case "Single Stub - Series"
                    % Aquire the circle of constant gamma magnitude
                    [gamma, circleEqn] = gammaCircle(app, Z_L, TLR_0);

                    % Enter The Smith chart from the Z_L point
                    [Z_lx, Z_ly] = enterChart(app, Z_L, TLR_0);
                    plot(app.UIAxes,Z_lx, Z_ly,'-o', "Color",[0 0 1], "LineWidth",3)
                    hold(app.UIAxes, 'on')

                    % Get the admittance
                    [Y_lx, Y_ly] = getAdmittancePt(app, Z_lx, Z_ly);
                    plot(app.UIAxes, Y_lx, Y_ly, '-o', "Color",[1 0 0], "LineWidth",3)


                    %gammaMag = abs(gamma);

                    fimplicit(app.UIAxes, circleEqn, 'Color',[0 0 0])
  
                    [newX, newY, d] = moveToR1onChart(app, Z_L, TLR_0);
                    plot(app.UIAxes,newX, newY, 'X', 'LineWidth',3)
                    dStr = strcat(num2str(d(1)),"   ",num2str(d(2)));
                    uialert(app.UIFigure, dStr,"RESULTS")

                    %fimplicit(app.UIAxes, conR)

                    %xlim(app.UIAxes, [-gammaMag gammaMag])
                    %ylim(app.UIAxes, [-gammaMag gammaMag])


                case "Single Stub - Parallel"

                case "Double Stub - Parallel"

            end


        end

        % Value changed function: sR
        function sRValueChanged(app, event)
            % Update the label value to match the user input
            if app.sI.Value < 0
                app.slabel.Text = "Z = " +num2str(app.sR.Value) + "-j" + num2str(abs(app.sI.Value)) + " Ω";
            elseif app.sI.Value == 0
                app.slabel.Text = "Z = " +num2str(app.sR.Value) + " Ω";

            else
                app.slabel.Text = "Z = " +num2str(app.sR.Value) + "+j" + num2str(app.sI.Value) + " Ω" ;
            end
            if app.sR.Value == 0 && app.sI.Value ~= 0
                if app.sI.Value == 0
                    app.slabel.Text = "Z = 0 Ω" ;
                elseif app.sI.Value < 0
                    app.slabel.Text = "Z = -j" + num2str(abs(app.sI.Value)) + " Ω" ;
                else
                    app.slabel.Text = "Z = j" + num2str(app.sI.Value) + " Ω" ;
                end
            end
        end

        % Value changed function: sI
        function sIValueChanged(app, event)
            % Update the label value to match the user input
            if app.sI.Value < 0
                app.slabel.Text = "Z = " +num2str(app.sR.Value) + "-j" + num2str(abs(app.sI.Value)) + " Ω";
            elseif app.sI.Value == 0
                app.slabel.Text = "Z = " +num2str(app.sR.Value) + " Ω";

            else
                app.slabel.Text = "Z = " +num2str(app.sR.Value) + "+j" + num2str(app.sI.Value) + " Ω" ;
            end
            if app.sR.Value == 0 && app.sI.Value ~= 0
                if app.sI.Value == 0
                    app.slabel.Text = "Z = 0 Ω" ;
                elseif app.sI.Value < 0
                    app.slabel.Text = "Z = -j" + num2str(abs(app.sI.Value)) + " Ω" ;
                else
                    app.slabel.Text = "Z = j" + num2str(app.sI.Value) + " Ω" ;
                end
            end
        end

        % Selection changed function: StubPropertiesButtonGroup
        function StubPropertiesButtonGroupSelectionChanged(app, event)
            selectedButton = app.StubPropertiesButtonGroup.SelectedObject;
            if selectedButton == app.ShortCircuitTerminationButton
                app.sI.Enable = 'off';
                app.sR.Enable = 'off';
                app.slabel.Text = "Z = 0 Ω";
                app.sI.Value = 0;
                app.sR.Value = 0;
            elseif selectedButton == app.OpenCircuitTerminationButton
                app.sI.Enable = 'off';
                app.sR.Enable = 'off';
                app.slabel.Text = "Z = ∞ Ω";
                app.sI.Value = inf;
                app.sR.Value = inf;
            else
                app.sI.Enable = 'on';
                app.sR.Enable = 'on';
                app.sI.Value = 0;
                app.sR.Value = 0;
                sRValueChanged(app, event)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.LoadPropertiesPanel = uipanel(app.UIFigure);
            app.LoadPropertiesPanel.Title = 'Load Properties';
            app.ImpedanceRealPartEditFieldLabel = uilabel(app.LoadPropertiesPanel);
            app.ImpedanceRealPartEditFieldLabel.Text = 'Impedance Real Part';
            app.zlReal = uieditfield(app.LoadPropertiesPanel, 'numeric');
            app.zlReal.ValueDisplayFormat = '%11.9g';
            app.zlReal.ValueChangedFcn = createCallbackFcn(app, @zlRealValueChanged, true);
            app.zlReal.Value = 25;
            app.ImpedanceImaginaryPartEditFieldLabel = uilabel(app.LoadPropertiesPanel);
            app.ImpedanceImaginaryPartEditFieldLabel.Text = 'Impedance Imaginary Part';
            app.zlImag = uieditfield(app.LoadPropertiesPanel, 'numeric');
            app.zlImag.ValueDisplayFormat = '%11.9g';
            app.zlImag.ValueChangedFcn = createCallbackFcn(app, @zlImagValueChanged, true);
            app.zlImag.Value = 25;
            app.ZrealjImagLabel = uilabel(app.LoadPropertiesPanel);
            app.ZrealjImagLabel.Text = 'Z =  real+jImag';
            app.MatchingNetworkTypeDropDown = uidropdown(app.UIFigure);
            app.MatchingNetworkTypeDropDown.Items = {'Single Stub - Series', 'Single Stub - Parallel', ''};
            app.MatchingNetworkTypeDropDown.Value = 'Single Stub - Series';
            app.MatchingNetworkTypeDropDownLabel = uilabel(app.UIFigure);
            app.MatchingNetworkTypeDropDownLabel.Text = 'Matching Network Type';
            app.SourcePropertiesPanel = uipanel(app.UIFigure);
            app.SourcePropertiesPanel.Title = 'Source Properties';
            app.WavelengthmLabel = uilabel(app.SourcePropertiesPanel);
            app.WavelengthmLabel.Text = 'Wavelength (m)';
            app.wavelength = uieditfield(app.SourcePropertiesPanel, 'numeric');
            app.wavelength.ValueDisplayFormat = '%11.9g';
            app.wavelength.Value = 1;
            app.DesignNetworkButton = uibutton(app.UIFigure, 'push');
            app.DesignNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @DesignNetworkButtonPushed, true);
            app.DesignNetworkButton.Text = 'Design Network';
            app.StubPropertiesButtonGroup = uibuttongroup(app.UIFigure);
            app.StubPropertiesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StubPropertiesButtonGroupSelectionChanged, true);
            app.StubPropertiesButtonGroup.Title = 'Stub Properties';
            app.OpenCircuitTerminationButton = uiradiobutton(app.StubPropertiesButtonGroup);
            app.OpenCircuitTerminationButton.Text = 'Open Circuit Termination';
            app.ShortCircuitTerminationButton = uiradiobutton(app.StubPropertiesButtonGroup);
            app.ShortCircuitTerminationButton.Text = 'Short Circuit Termination';
            app.ShortCircuitTerminationButton.Value = true;
            app.GeneralImpedanceButton = uiradiobutton(app.StubPropertiesButtonGroup);
            app.GeneralImpedanceButton.Text = 'General Impedance';
            app.slabel = uilabel(app.StubPropertiesButtonGroup);
            app.slabel.Text = 'Z =  real+jImag';
            app.ImpedanceRealPartEditFieldLabel_2 = uilabel(app.StubPropertiesButtonGroup);
            app.ImpedanceRealPartEditFieldLabel_2.Text = 'Impedance Real Part';
            app.sR = uieditfield(app.StubPropertiesButtonGroup, 'numeric');
            app.sR.ValueDisplayFormat = '%11.9g';
            app.sR.ValueChangedFcn = createCallbackFcn(app, @sRValueChanged, true);
            app.ImpedanceImaginaryPartEditFieldLabel_2 = uilabel(app.StubPropertiesButtonGroup);
            app.ImpedanceImaginaryPartEditFieldLabel_2.Text = 'Impedance Imaginary Part';
            app.sI = uieditfield(app.StubPropertiesButtonGroup, 'numeric');
            app.sI.ValueDisplayFormat = '%11.9g';
            app.sI.ValueChangedFcn = createCallbackFcn(app, @sIValueChanged, true);
            app.TransmissionLinePropertiesPanel_2 = uipanel(app.UIFigure);
            app.TransmissionLinePropertiesPanel_2.Title = 'Transmission Line Properties';
            app.CharachteristicImpedanceLabel_2 = uilabel(app.TransmissionLinePropertiesPanel_2);
            app.CharachteristicImpedanceLabel_2.Text = 'Charachteristic Impedance (Ω)';
            app.tlR0 = uieditfield(app.TransmissionLinePropertiesPanel_2, 'numeric');
            app.tlR0.ValueDisplayFormat = '%11.9g';
            app.tlR0.Value = 50;
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matcher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end